classdef searchAlgorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SearchAlgorithmDropDownLabel  matlab.ui.control.Label
        SearchAlgorithmDropDown       matlab.ui.control.DropDown
        ResolutionSpinnerLabel        matlab.ui.control.Label
        ResolutionSpinner             matlab.ui.control.Spinner
        MapDropDownLabel              matlab.ui.control.Label
        MapDropDown                   matlab.ui.control.DropDown
        StartPositionPanel            matlab.ui.container.Panel
        XLabel_Start                  matlab.ui.control.Label
        XEditField_Start              matlab.ui.control.NumericEditField
        YLabel_Start                  matlab.ui.control.Label
        YEditField_Start              matlab.ui.control.NumericEditField
        GoalPositionPanel             matlab.ui.container.Panel
        XLabel_Goal                   matlab.ui.control.Label
        XEditField_Goal               matlab.ui.control.NumericEditField
        YLabel_Goal                   matlab.ui.control.Label
        YEditField_Goal               matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        SetPositionsButton            matlab.ui.control.Button
        ActionSpaceDropDownLabel      matlab.ui.control.Label
        ActionSpaceDropDown           matlab.ui.control.DropDown
        DistanceDropDownLabel         matlab.ui.control.Label
        DistanceDropDown              matlab.ui.control.DropDown
        CommentsTextAreaLabel         matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
        RobotTypeDropDownLabel        matlab.ui.control.Label
        RobotTypeDropDown             matlab.ui.control.DropDown
        RobotRadiusSpinnerLabel       matlab.ui.control.Label
        RobotRadiusSpinner            matlab.ui.control.Spinner
        ClearanceSpinnerLabel         matlab.ui.control.Label
        ClearanceSpinner              matlab.ui.control.Spinner
        StartButton                   matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        actions = [0 1; 1 0; 0 -1; -1 0];                                       % {[0 1; 1 0; 0 -1; -1 0], [0 1; 1 1; 1 0; 1 -1; 0 -1; -1 -1; -1 0; -1 1]}
        clearance = 0;                                                          % Set when robot is rigid using the respective dialog box
        distance = 'Manhattan';                                                 % {'Manhattan', 'Euclidean'}
        goalPos = [250 150];                                                    % Set using the respective dialog box
        map = {};                                                               % Create a cell to store all values related to map - grids, obstacles
        mapName = 'Map 2018';                                                   % {'Map 2018', 'Map 2019'}
        obsSpace = containers.Map('KeyType','double','ValueType','any');        % A dictionary to store all indices identified as obstacles
        radius = 0;                                                             % Set when robot is rigid using the respective dialog box
        res = 5;                                                                % Set using the respective dialog box
        searchAlgo = 'BFS';                                                     % {'BFS', 'DFS', 'Dijkstra', 'A*', 'Weighted A*'}
        shape = {};                                                             % Save the values for the shapes - rectangle, concave, circle, ellipse
        startPos = [0 0];                                                       % Set using the respective dialog box
    end
    
    methods (Access = private)
        
        %%%% Plot the actual map
        function plotMap(app)
            % Clear the axes
            cla(app.UIAxes);
            
            % Do not show the grid
            grid(app.UIAxes, 'off')
            
            % Define the boundary margin
            margin = app.radius + app.clearance;
            
            % Switch hold on
            hold(app.UIAxes, 'on');
            
            % Real Obstacle 1 - Rectangle
            rectangle(app.UIAxes, 'Position', app.shape{1} + margin*[-1 -1 2 2], 'FaceColor', 'k');
            
            % Real Obstacle 2 - Concave Shape
            if margin ~= 0
                tempShape = diff([app.shape{2}; app.shape{2}(1:2,:)]);
                angles = atan2(abs(tempShape(2:end,1).*tempShape(1:end-1,2) - tempShape(1:end-1,1).*tempShape(2:end,2)), ...
                               -(tempShape(1:end-1,1).*tempShape(2:end,1) + tempShape(1:end-1,2).*tempShape(2:end,2)));
                slopes = atan2(tempShape(2:end,2), tempShape(2:end,1));
                newSlopes = [slopes(1) + angles(1)/2; slopes(2:end) - angles(2:end)/2 - pi];
                newSlopes(newSlopes < -pi) = newSlopes(newSlopes < -pi) + 2*pi;
                changeInShape = margin * [cos(newSlopes), sin(newSlopes)];
                changeInShape = [changeInShape(end,:); changeInShape(1:end-1,:)];
                concShape = app.shape{2} + changeInShape;
            else
                concShape = app.shape{2};
            end
            patch(app.UIAxes, concShape(:,1), concShape(:,2), 'k');
            
            % Real Obstacle 3 - Circle
            rectangle(app.UIAxes, 'Position', app.shape{3} + margin*[-1 -1 2 2], 'Curvature', [1 1], 'FaceColor', 'k');
            
            % Real Obstacle 4 - Ellipse
            if ~isempty(app.shape{4})
                rectangle(app.UIAxes, 'Position', app.shape{4} + margin*[-1 -1 2 2], 'Curvature', [1 1], 'FaceColor', 'k');
            end
            
            % Boundary
            if margin ~= 0
                % Top Boundary
                rectangle(app.UIAxes, 'Position', [0 150-margin 250 margin], 'FaceColor', 'k');
                % Right Boundary
                rectangle(app.UIAxes, 'Position', [250-margin 0 margin 150], 'FaceColor', 'k');
                % Bottom Boundary
                rectangle(app.UIAxes, 'Position', [0 0 250 margin], 'FaceColor', 'k');
                % Left Boundary
                rectangle(app.UIAxes, 'Position', [0 0 margin 150], 'FaceColor', 'k');
            end
            
            % Switch hold off
            hold(app.UIAxes, 'off');
        end
        
        %%%% Toggle GUI objects - settings
        function toggleSettings(app, value)
            % Input 1 - Search Algorithm
            app.SearchAlgorithmDropDownLabel.Enable = value;
            app.SearchAlgorithmDropDown.Enable = value;
            
            % Input 2 - Action Space
            app.ActionSpaceDropDownLabel.Enable = value;
            app.ActionSpaceDropDown.Enable = value;
            
            % Input 3 - Distance
            app.DistanceDropDownLabel.Enable = value;
            app.DistanceDropDown.Enable = value;
            
            % Input 4 - Map
            app.MapDropDownLabel.Enable = value;
            app.MapDropDown.Enable = value;
            
            % Input 5 - Resolution
            app.ResolutionSpinnerLabel.Enable = value;
            app.ResolutionSpinner.Enable = value;
            
            % Input 6 - Robot Type
            app.RobotTypeDropDownLabel.Enable = value;
            app.RobotTypeDropDown.Enable = value;
            
            % Input 7 - Robot Radius
            app.RobotRadiusSpinnerLabel.Enable = value;
            app.RobotRadiusSpinner.Enable = value;
            
            % Input 8 - Clearance
            app.ClearanceSpinnerLabel.Enable = value;
            app.ClearanceSpinner.Enable = value;
        end
        
        %%%% Toggle GUI objects - positions
        function togglePositions(app, value)
            % Input 1 - Start Position X
            app.XLabel_Start.Enable = value;
            app.XEditField_Start.Enable = value;
            
            % Input 2 - Start Position Y
            app.YLabel_Start.Enable = value;
            app.YEditField_Start.Enable = value;
            
            % Input 3 - Goal Position X
            app.XLabel_Goal.Enable = value;
            app.XEditField_Goal.Enable = value;
            
            % Input 4 - Goal Position Y
            app.YLabel_Goal.Enable = value;
            app.YEditField_Goal.Enable = value;
        end
        
        %%%% Generate unique number
        function num = nodeNumber(~, X, Y)
            num = (2.^X).*(3.^Y);
        end
        
        %%%% Plot the grid map
        function plotGridMap(app)
            % Clear the axes
            cla(app.UIAxes);
            
            % Show the grid
            if app.res >= 5
                grid(app.UIAxes, 'on')
            end
            
            % Define the boundary margin
            margin = app.radius + app.clearance;
            
            % Switch hold on
            hold(app.UIAxes, 'on');
            
            % Rectangle
            rect = [app.shape{1}(1:2) - margin, app.shape{1}(1:2) + app.shape{1}(3:4) + margin];
            obs{1} = ((app.map{2} <= rect(4)) | (app.map{2} - (app.res/2) < rect(4))) & ...   % top
                     ((app.map{1} <= rect(3)) | (app.map{1} - (app.res/2) < rect(3))) & ...   % right
                     ((app.map{2} >= rect(2)) | (app.map{2} + (app.res/2) > rect(2))) & ...   % bottom
                     ((app.map{1} >= rect(1)) | (app.map{1} + (app.res/2) > rect(1)));        % left
            
            % Concave Polygon
            concShape = [app.shape{2}; app.shape{2}(1,:)];
            p = [];
            for i = [3:6, 1:2]
                p = [p; polyfit(concShape(i:i+1,1), concShape(i:i+1,2), 1)];
            end
            cDiff = margin*sqrt(1 + p(:,1).^2);
            p(:,2) = p(:,2) + cDiff.*[1 -1 -1 -1 1 1]';
            obs{2} = ((app.map{2} <= p(1,1)*app.map{1} + p(1,2)) | (app.map{2} - (app.res/2) < p(1,1)*(app.map{1} - (app.res/2)) + p(1,2))) & ...
                     ((app.map{2} >= p(2,1)*app.map{1} + p(2,2)) | (app.map{2} + (app.res/2) > p(2,1)*(app.map{1} - (app.res/2)) + p(2,2))) & ...
                     ((app.map{2} >= p(3,1)*app.map{1} + p(3,2)) | (app.map{2} + (app.res/2) > p(3,1)*app.map{1} + p(3,2))) & ...
                     ((app.map{2} >= p(4,1)*app.map{1} + p(4,2)) | (app.map{2} + (app.res/2) > p(4,1)*(app.map{1} + (app.res/2)) + p(4,2))) & ...
                     (((app.map{2} <= p(5,1)*app.map{1} + p(5,2)) | (app.map{2} - (app.res/2) < p(5,1)*(app.map{1} - (app.res/2)) + p(5,2))) | ...
                      ((app.map{2} <= p(6,1)*app.map{1} + p(6,2)) | (app.map{2} - (app.res/2) < p(6,1)*(app.map{1} + (app.res/2)) + p(6,2))));
            
            % Circle
            circ = [app.shape{3}(1:2) + (app.shape{3}(3:4)/2), (app.shape{3}(3)/2) + margin];
            obs{3} = ((app.map{1} - circ(1)).^2 + (app.map{2} - circ(2)).^2 <= circ(3)^2) | ...
                     ((app.map{1} + (app.res/2) - circ(1)).^2 + (app.map{2} + (app.res/2) - circ(2)).^2 < circ(3)^2) | ...
                     ((app.map{1} + (app.res/2) - circ(1)).^2 + (app.map{2} - (app.res/2) - circ(2)).^2 < circ(3)^2) | ...
                     ((app.map{1} - (app.res/2) - circ(1)).^2 + (app.map{2} - (app.res/2) - circ(2)).^2 < circ(3)^2) | ...
                     ((app.map{1} - (app.res/2) - circ(1)).^2 + (app.map{2} + (app.res/2) - circ(2)).^2 < circ(3)^2);
            
            % Ellipse
            if ~isempty(app.shape{4})
                elli = [app.shape{4}(1:2) + (app.shape{4}(3:4)/2), (app.shape{4}(3:4)/2) + margin];
                obs{4} = ((((app.map{1} - elli(1)).^2)/elli(3)^2) + (((app.map{2} - elli(2)).^2)/elli(4)^2) <= 1) | ...
                         ((((app.map{1} + (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} + (app.res/2) - elli(2)).^2)/elli(4)^2) < 1) | ...
                         ((((app.map{1} + (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} - (app.res/2) - elli(2)).^2)/elli(4)^2) < 1) | ...
                         ((((app.map{1} - (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} - (app.res/2) - elli(2)).^2)/elli(4)^2) < 1) | ...
                         ((((app.map{1} - (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} + (app.res/2) - elli(2)).^2)/elli(4)^2) < 1);
            else
                obs{4} = zeros(size(app.map{3}));
            end
            
            % Boundary
            if margin ~= 0
                boundary = [150 - margin, 250 - margin, margin, margin];
                obs{5} = ((app.map{2} >= boundary(1)) | (app.map{2} + (app.res/2) > boundary(1))) | ...   % top
                         ((app.map{1} >= boundary(2)) | (app.map{1} + (app.res/2) > boundary(2))) | ...   % right
                         ((app.map{2} <= boundary(3)) | (app.map{2} - (app.res/2) < boundary(3))) | ...   % bottom
                         ((app.map{1} <= boundary(4)) | (app.map{1} - (app.res/2) < boundary(4)));        % left
            else
                obs{5} = zeros(size(app.map{3}));
            end
            
            % All obstacles
            O = obs{1} | obs{2} | obs{3} | obs{4} | obs{5};
            
            % Find the obstacles
            ind = find(O);
            x = app.map{1}(ind);
            y = app.map{2}(ind);
            num = app.map{3}(ind);
            
            % Store the obstacle space
            remove(app.obsSpace, keys(app.obsSpace));
            for i = 1:numel(ind)
                app.obsSpace(num(i)) = [x(i) y(i)];
                rectangle(app.UIAxes, 'Position', [x(i)-(app.res/2) y(i)-(app.res/2) app.res app.res], 'FaceColor', 'k');
            end
            rectangle(app.UIAxes, 'Position', app.shape{1}, 'EdgeColor', 'm');
            line(app.UIAxes, concShape(:,1), concShape(:,2), 'Color', 'm');
            rectangle(app.UIAxes, 'Position', app.shape{3}, 'Curvature', [1 1], 'EdgeColor', 'm');
            if ~isempty(app.shape{4})
                rectangle(app.UIAxes, 'Position', app.shape{4}, 'Curvature', [1 1], 'EdgeColor', 'm');
            end
            
            % Switch hold off
            hold(app.UIAxes, 'off');
        end
        
        %%%% Get grid position for given coordinates
        function gridPos = world2grid(app, worldPos)
            % Initialize possible x and y positions
            X = app.map{1}(1,:);
            Y = app.map{2}(:,1);
            
            % Start conversion
            for i = 1:size(worldPos, 1)
                % Locate x grid position
                x_grid = find(worldPos(i,1) <= X, 1);
                if isempty(x_grid)
                    x_grid = numel(X);
                elseif worldPos(i,1) + (app.res/2) < X(x_grid)
                    x_grid = x_grid - 1;
                end
                
                % Locate y grid position
                y_grid = find(worldPos(i,2) <= Y, 1);
                if isempty(y_grid)
                    y_grid = numel(Y);
                elseif worldPos(i,2) + (app.res/2) < Y(y_grid)
                    y_grid = y_grid - 1;
                end
                
                % Define the point
                gridPos(i,:) = [X(x_grid), Y(y_grid)];
            end
        end


        function path = astar_proj2(app, res, start, goal, createVideo)
            
            % Set the initial node
            init_node.pos = start_pos;
            init_node.gcost = 0;
            init_node.hcost = norm(start_pos-goal_pos);
            init_node.fcost = init_node.gcost + init_node.hcost;
            init_node.parent = struct;
            
            % Start an OPEN list
            OPEN = containers.Map(init_node.fcost,init_node);
            k = init_node.fcost;
            
            % Define all possible actions
            actions = [res, 0; res, res; 0, res; -res, res; -res, 0; -res, -res; 0, -res; res, -res];
            
            % Start a CLOSED list
            CLOSED = zeros(length(map{1}),length(map{2}));
            
            % Start a path list
            path = goal_pos;
            tol = res/1e4;
            
            % Search algorithm
            while ~isempty(OPEN)
                if length(OPEN(min(k)))~=1
                    OPEN_temp = OPEN(min(k));
                    current_node = OPEN_temp(1);
                    OPEN_temp(1:end-1) = OPEN_temp(2:end);
                    OPEN_temp(end) = [];
                    OPEN(min(k)) = OPEN_temp;
                else
                    current_node = OPEN(min(k));
                    remove(OPEN,min(k));
                    k = k(abs(k-min(k))>tol);
                end
                
                if all(abs(current_node.pos - goal_pos)<tol)
                    parent_node = current_node.parent;
                    while any(abs(parent_node.pos - start_pos)>tol)
                        path = [path; parent_node.pos];
                        parent_node = parent_node.parent;
                    end
                    break;
                end
                
                x = find(abs(map{1}-current_node.pos(1))<tol,1);
                y = find(abs(map{2}-current_node.pos(2))<tol,1);
                if CLOSED(x,y) == 1
                    continue;
                end
                CLOSED(x,y) = 1;
                % expand
                for i=1:length(actions)
                    neighbor_node.pos = current_node.pos + actions(i,:);
                    % check if it intersects with an obstacle (also checks if it
                    % lies inside the boundary of the map)
                    if collide_proj2(map,neighbor_node.pos)
                        continue;
                    end
                    % check if closed already
                    if CLOSED(abs(map{1}-neighbor_node.pos(1))<tol,abs(map{2}-neighbor_node.pos(2))<tol)
                        continue;
                    end
                    neighbor_node.gcost = current_node.gcost + norm(neighbor_node.pos-current_node.pos);
                    neighbor_node.hcost = norm(neighbor_node.pos - goal_pos);
                    neighbor_node.fcost = neighbor_node.gcost + neighbor_node.hcost;
                    neighbor_node.parent = current_node;
                    if isKey(OPEN,neighbor_node.fcost)
                        OPEN(neighbor_node.fcost) = [OPEN(neighbor_node.fcost);neighbor_node];
                    else
                        OPEN(neighbor_node.fcost) = neighbor_node;
                        k(end+1) = neighbor_node.fcost;
                    end
                    if any(abs(neighbor_node.pos - goal_pos)>tol)
                        rectangle('Position',[neighbor_node.pos(1)-(res/2) neighbor_node.pos(2)-(res/2) res res],'FaceColor','y');
                        if createVideo
                            writeVideo(vidObj, getframe(gcf));
                        end
                        pause(0.001)
                    end
                end
            end
            path = [path; start_pos];
            path = flipud(path);
            
            % Plot path
            title('Path Found')
            for i=2:(size(path,1)-1)
                rectangle('Position',[path(i,1)-(res/2) path(i,2)-(res/2) res res],'FaceColor','c');
                if createVideo
                    writeVideo(vidObj, getframe(gcf));
                end
                pause(0.01)
            end
            plot(path(:,1),path(:,2))
            hold off
            
            if createVideo
                for i = 1:5*vidObj.FrameRate
                    writeVideo(vidObj, getframe(gcf));
                end
                close(vidObj);
            end
            
            % Save the figure
            saveas(gcf,['../output/astar_path_proj2 (res = ' num2str(res) ').jpg']);
        
        end
%         function results = func(app)
%             app.myAx = subplot(2,1,1,'Parent',app.Panel);  % create plot
% app.myAx.ButtonDownFcn = createCallbackFcn(app, @app.getMousePosition, true);
% function getMousePosition(app,source,event)
%     switch event.EventName
%         case 'Hit'
%             % Properties: Btn/IntersectionPoint/Src/Evnt
%             posData = event.IntersectionPoint; % to be used for calc
%         case 'MouseData'
%             % Properties: Src/EvntName
%     end
% end
%         end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the GUI in the center of the screen
            screenSize = get(0,'ScreenSize');
            edge = (screenSize(3:4) - app.UIFigure.Position(3:4))/2;
            edge(edge < 0) = 0;
            app.UIFigure.Position = [edge app.UIFigure.Position(3:4)];
            drawnow;
            
            % Plot the map
            app.UIAxes.XTick = 0:app.res:250;
            app.UIAxes.YTick = 0:app.res:150;
            xtickangle(app.UIAxes, 90);
            app.shape = {[55 67.5 50 45]; [120 55; 158 51; 165 89; 188 51; 168 14; 145 14]; [165 105 30 30]; []};
            plotMap(app);
        end

        % Value changed function: SearchAlgorithmDropDown
        function SearchAlgorithmDropDownValueChanged(app, event)
            app.searchAlgo = app.SearchAlgorithmDropDown.Value;
        end

        % Value changed function: ActionSpaceDropDown
        function ActionSpaceDropDownValueChanged(app, event)
            selectedObject = app.ActionSpaceDropDown.Value;
            if strcmp(selectedObject, '4-connected')
                app.actions = [0 1; 1 0; 0 -1; -1 0];
            elseif strcmp(selectedObject, '8-connected')
                app.actions = [0 1; 1 1; 1 0; 1 -1; 0 -1; -1 -1; -1 0; -1 1];
            end
        end

        % Value changed function: DistanceDropDown
        function DistanceDropDownValueChanged(app, event)
            app.distance = app.DistanceDropDown.Value;
        end

        % Value changed function: MapDropDown
        function MapDropDownValueChanged(app, event)
            app.mapName = app.MapDropDown.Value;
            
            % Set the values for shapes
            if strcmp(app.mapName, 'Map 2018')
                % Real Obstacle 1 - Rectangle
                app.shape{1} = [55 67.5 50 45];
                % Real Obstacle 2 - Concave Shape
                app.shape{2} = [120 55; 158 51; 165 89; 188 51; 168 14; 145 14];
                % Real Obstacle 3 - Circle
                app.shape{3} = [165 105 30 30];
                % No fourth obstacle
                app.shape{4} = [];
            elseif strcmp(app.mapName, 'Map 2019')
                % Real Obstacle 1 - Rectangle
                app.shape{1} = [50 67.5 50 45];
                % Real Obstacle 2 - Concave Shape
                app.shape{2} = [125 56; 163 52; 170 90; 193 52; 173 15; 150 15];
                % Real Obstacle 3 - Circle
                app.shape{3} = [175 115 30 30];
                % Real Obstacle 4 - Ellipse
                app.shape{4} = [125 114 30 12];
            end
            
            % Plot the updated map
            plotMap(app);
        end

        % Value changed function: ResolutionSpinner
        function ResolutionSpinnerValueChanged(app, event)
            value = app.ResolutionSpinner.Value;
            
            % Check if the value is still changing
            pause on
            pause(1)
            while value ~= app.ResolutionSpinner.Value
                value = app.ResolutionSpinner.Value;
                pause(1)
            end
            
            % Check if the resolution is feasible
            if (rem(250, value) ~= 0) || (rem(150, value) ~= 0)
                app.ResolutionSpinner.Value = app.res;
                app.CommentsTextArea.Value = {'Uniform Grid cannot be formed for the given resolution.'};
            else
                app.res = value;
            end
            pause(2)
            app.CommentsTextArea.Value = {'Select all the parameters and then move on to select the start and goal positions.'};
            pause off
            
            % Update the grid size
            if app.res >= 5
                app.UIAxes.XTick = 0:app.res:250;
                app.UIAxes.YTick = 0:app.res:150;
            end
        end

        % Value changed function: RobotTypeDropDown
        function RobotTypeDropDownValueChanged(app, event)
            selectedObject = app.RobotTypeDropDown.Value;
            if strcmp(selectedObject, 'Point')
                app.RobotRadiusSpinner.Visible = 'off';
                app.RobotRadiusSpinnerLabel.Visible = 'off';
                app.ClearanceSpinner.Visible = 'off';
                app.ClearanceSpinnerLabel.Visible = 'off';
                app.radius = 0;
                app.clearance = 0;
            elseif strcmp(selectedObject, 'Rigid')
                app.RobotRadiusSpinner.Visible = 'on';
                app.RobotRadiusSpinnerLabel.Visible = 'on';
                app.ClearanceSpinner.Visible = 'on';
                app.ClearanceSpinnerLabel.Visible = 'on';
                app.radius = app.RobotRadiusSpinner.Value;
                app.clearance = app.ClearanceSpinner.Value;
            end
            plotMap(app);
        end

        % Value changed function: RobotRadiusSpinner
        function RobotRadiusSpinnerValueChanged(app, event)
            app.radius = app.RobotRadiusSpinner.Value;
            plotMap(app);
        end

        % Value changed function: ClearanceSpinner
        function ClearanceSpinnerValueChanged(app, event)
            app.clearance = app.ClearanceSpinner.Value;
            plotMap(app);
        end

        % Button pushed function: SetPositionsButton
        function SetPositionsButtonPushed(app, event)
            % Change the button visibility
            app.SetPositionsButton.Visible = 'off';
            app.StartButton.Visible = 'on';
            app.BackButton.Visible = 'on';
            
            % Toggle the GUI objects
            toggleSettings(app, 'off');
            togglePositions(app, 'on');
            
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Select the positions and then start the path planning algorithm. You can go back to modify the parameters.'};
            
            % Create Grid Map
            x = 0+(app.res/2):app.res:250-(app.res/2);
            y = 0+(app.res/2):app.res:150-(app.res/2);
            [app.map{1}, app.map{2}] = meshgrid(x, y);
            app.map{3} = nodeNumber(app, app.map{1}, app.map{2});
            
            % Plot the grid map
            plotGridMap(app)
            
            % Convert start and goal position to grid positions
            gridPos = world2grid(app, [app.startPos; app.goalPos]);
            
            % Find the first free space
            if isKey(app.obsSpace, nodeNumber(app, gridPos(1,1), gridPos(1,2)))
                for i = 1:numel(app.map{3})
                    if ~isKey(app.obsSpace, app.map{3}(i))
                        app.startPos = [app.map{1}(i), app.map{2}(i)];
                        app.XEditField_Start.Value = app.startPos(1);
                        app.YEditField_Start.Value = app.startPos(2);
                        break;
                    end
                end
            end
            
            % Find the last free space
            if isKey(app.obsSpace, nodeNumber(app, gridPos(2,1), gridPos(2,2)))
                for i = numel(app.map{3}):-1:1
                    if ~isKey(app.obsSpace, app.map{3}(i))
                        app.goalPos = [app.map{1}(i), app.map{2}(i)];
                        app.XEditField_Goal.Value = app.goalPos(1);
                        app.YEditField_Goal.Value = app.goalPos(2);
                        break;
                    end
                end
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Change the button visibility
            app.SetPositionsButton.Visible = 'on';
            app.StartButton.Visible = 'off';
            app.BackButton.Visible = 'off';
            
            % Toggle the GUI objects
            toggleSettings(app, 'on');
            togglePositions(app, 'off');
            
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Select all the parameters and then move on to select the start and goal positions.'};
            
            % Plot the actual map
            plotMap(app)
        end

        % Value changed function: XEditField_Start
        function XEditField_StartValueChanged(app, event)
            app.startPos(1) = app.XEditField_Start.Value;
        end

        % Value changed function: YEditField_Start
        function YEditField_StartValueChanged(app, event)
            app.startPos(2) = app.YEditField_Start.Value;
        end

        % Value changed function: XEditField_Goal
        function XEditField_GoalValueChanged(app, event)
            app.goalPos(1) = app.XEditField_Goal.Value;
        end

        % Value changed function: YEditField_Goal
        function YEditField_GoalValueChanged(app, event)
            app.goalPos(2) = app.YEditField_Goal.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Convert start and goal position to grid positions
            gridPos = world2grid(app, [app.startPos; app.goalPos]);
            
            % Check if start position is in the free space
            if ~isKey(app.obsSpace, nodeNumber(app, gridPos(1,1), gridPos(1,2)))
                app.startPos = gridPos(1,:);
            else
                app.CommentsTextArea.Value = {'Start position is not in the free space. Try again or go back to modify the parameters.'};
                return;
            end
            
            % Check if goal position is in the free space
            if ~isKey(app.obsSpace, nodeNumber(app, gridPos(2,1), gridPos(2,2)))
                app.goalPos = gridPos(2,:);
            else
                app.CommentsTextArea.Value = {'Goal position is not in the free space. Try again or go back to modify the parameters.'};
                return;
            end
            
            % Plot the start and goal positions
            rectangle(app.UIAxes, 'Position', [app.startPos - (app.res/2), app.res, app.res], 'FaceColor', 'r');
            rectangle(app.UIAxes, 'Position', [app.goalPos - (app.res/2), app.res, app.res], 'FaceColor', 'b');
            
            % Start the Planning Algorithm
            app.CommentsTextArea.Value = {['Starting Path Planning using ' app.searchAlgo]};
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SearchAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.SearchAlgorithmDropDownLabel.Text = 'Search Algorithm';
            app.SearchAlgorithmDropDown = uidropdown(app.UIFigure);
            app.SearchAlgorithmDropDown.Items = {'BFS', 'DFS', 'Dijkstra', 'A*', 'Weighted A*'};
            app.SearchAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchAlgorithmDropDownValueChanged, true);
            app.SearchAlgorithmDropDown.Value = 'BFS';
            app.ResolutionSpinnerLabel = uilabel(app.UIFigure);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.UIFigure);
            app.ResolutionSpinner.Limits = [0 Inf];
            app.ResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanged, true);
            app.ResolutionSpinner.Value = 5;
            app.MapDropDownLabel = uilabel(app.UIFigure);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.UIFigure);
            app.MapDropDown.Items = {'Map 2018', 'Map 2019'};
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @MapDropDownValueChanged, true);
            app.MapDropDown.Value = 'Map 2018';
            app.StartPositionPanel = uipanel(app.UIFigure);
            app.StartPositionPanel.Title = 'Start Position';
            app.XLabel_Start = uilabel(app.StartPositionPanel);
            app.XLabel_Start.Enable = 'off';
            app.XLabel_Start.Text = 'X :';
            app.XEditField_Start = uieditfield(app.StartPositionPanel, 'numeric');
            app.XEditField_Start.Limits = [0 250];
            app.XEditField_Start.ValueChangedFcn = createCallbackFcn(app, @XEditField_StartValueChanged, true);
            app.XEditField_Start.Enable = 'off';
            app.YLabel_Start = uilabel(app.StartPositionPanel);
            app.YLabel_Start.Enable = 'off';
            app.YLabel_Start.Text = 'Y :';
            app.YEditField_Start = uieditfield(app.StartPositionPanel, 'numeric');
            app.YEditField_Start.Limits = [0 150];
            app.YEditField_Start.ValueChangedFcn = createCallbackFcn(app, @YEditField_StartValueChanged, true);
            app.YEditField_Start.Enable = 'off';
            app.GoalPositionPanel = uipanel(app.UIFigure);
            app.GoalPositionPanel.Title = 'Goal Position';
            app.XLabel_Goal = uilabel(app.GoalPositionPanel);
            app.XLabel_Goal.Enable = 'off';
            app.XLabel_Goal.Text = 'X :';
            app.XEditField_Goal = uieditfield(app.GoalPositionPanel, 'numeric');
            app.XEditField_Goal.Limits = [0 250];
            app.XEditField_Goal.ValueChangedFcn = createCallbackFcn(app, @XEditField_GoalValueChanged, true);
            app.XEditField_Goal.Enable = 'off';
            app.XEditField_Goal.Value = 250;
            app.YLabel_Goal = uilabel(app.GoalPositionPanel);
            app.YLabel_Goal.Enable = 'off';
            app.YLabel_Goal.Text = 'Y :';
            app.YEditField_Goal = uieditfield(app.GoalPositionPanel, 'numeric');
            app.YEditField_Goal.Limits = [0 150];
            app.YEditField_Goal.ValueChangedFcn = createCallbackFcn(app, @YEditField_GoalValueChanged, true);
            app.YEditField_Goal.Enable = 'off';
            app.YEditField_Goal.Value = 150;
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X (in mm)')
            ylabel(app.UIAxes, 'Y (in mm)')
            app.UIAxes.XLim = [0 250];
            app.UIAxes.YLim = [0 150];
            app.UIAxes.XTick = [0 50 100 150 200 250];
            app.UIAxes.YTick = [0 50 100 150];
            app.SetPositionsButton = uibutton(app.UIFigure, 'push');
            app.SetPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetPositionsButtonPushed, true);
            app.SetPositionsButton.Text = 'Set the Start and Goal Positions';
            app.ActionSpaceDropDownLabel = uilabel(app.UIFigure);
            app.ActionSpaceDropDownLabel.Text = 'Action Space';
            app.ActionSpaceDropDown = uidropdown(app.UIFigure);
            app.ActionSpaceDropDown.Items = {'4-connected', '8-connected'};
            app.ActionSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionSpaceDropDownValueChanged, true);
            app.ActionSpaceDropDown.Value = '4-connected';
            app.DistanceDropDownLabel = uilabel(app.UIFigure);
            app.DistanceDropDownLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.UIFigure);
            app.DistanceDropDown.Items = {'Manhattan', 'Euclidean'};
            app.DistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @DistanceDropDownValueChanged, true);
            app.DistanceDropDown.Value = 'Manhattan';
            app.CommentsTextAreaLabel = uilabel(app.UIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.UIFigure);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Value = {'Select all the parameters and then move on to select the start and goal positions.'};
            app.RobotTypeDropDownLabel = uilabel(app.UIFigure);
            app.RobotTypeDropDownLabel.Text = 'Robot Type';
            app.RobotTypeDropDown = uidropdown(app.UIFigure);
            app.RobotTypeDropDown.Items = {'Point', 'Rigid'};
            app.RobotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotTypeDropDownValueChanged, true);
            app.RobotTypeDropDown.Value = 'Point';
            app.RobotRadiusSpinnerLabel = uilabel(app.UIFigure);
            app.RobotRadiusSpinnerLabel.Visible = 'off';
            app.RobotRadiusSpinnerLabel.Text = 'Robot Radius';
            app.RobotRadiusSpinner = uispinner(app.UIFigure);
            app.RobotRadiusSpinner.Limits = [0 Inf];
            app.RobotRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @RobotRadiusSpinnerValueChanged, true);
            app.RobotRadiusSpinner.Visible = 'off';
            app.RobotRadiusSpinner.Value = 5;
            app.ClearanceSpinnerLabel = uilabel(app.UIFigure);
            app.ClearanceSpinnerLabel.Visible = 'off';
            app.ClearanceSpinnerLabel.Text = 'Clearance';
            app.ClearanceSpinner = uispinner(app.UIFigure);
            app.ClearanceSpinner.Limits = [0 Inf];
            app.ClearanceSpinner.ValueChangedFcn = createCallbackFcn(app, @ClearanceSpinnerValueChanged, true);
            app.ClearanceSpinner.Visible = 'off';
            app.ClearanceSpinner.Value = 2;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Start';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Visible = 'off';
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = searchAlgorithm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end